classdef KarplusStrongApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CButton                       matlab.ui.control.Button
        DButton                       matlab.ui.control.Button
        EButton                       matlab.ui.control.Button
        FButton                       matlab.ui.control.Button
        GButton                       matlab.ui.control.Button
        AButton                       matlab.ui.control.Button
        BButton                       matlab.ui.control.Button
        CButton_2                     matlab.ui.control.Button
        DButton_2                     matlab.ui.control.Button
        FButton_2                     matlab.ui.control.Button
        GButton_2                     matlab.ui.control.Button
        AButton_2                     matlab.ui.control.Button
        CButton_3                     matlab.ui.control.Button
        DButton_3                     matlab.ui.control.Button
        EButton_2                     matlab.ui.control.Button
        CButton_4                     matlab.ui.control.Button
        DButton_4                     matlab.ui.control.Button
        OctaveDownButton              matlab.ui.control.Button
        OctaveUpButton                matlab.ui.control.Button
        FeedbackGainSliderLabel       matlab.ui.control.Label
        FeedbackGainSlider            matlab.ui.control.Slider
        LoopLowpassFilterCutoffFrequencySliderLabel  matlab.ui.control.Label
        LoopLowpassFilterCutoffFrequencySlider  matlab.ui.control.Slider
        ExcitationSignalButtonGroup   matlab.ui.container.ButtonGroup
        WhiteNoiseButton              matlab.ui.control.ToggleButton
        ChirpButton                   matlab.ui.control.ToggleButton
        SineButton                    matlab.ui.control.ToggleButton
        TriangleButton                matlab.ui.control.ToggleButton
        SquareButton                  matlab.ui.control.ToggleButton
        EndfrequencyHzEditFieldLabel  matlab.ui.control.Label
        EndfrequencyHzEditField       matlab.ui.control.NumericEditField
        DelayTimeKnobLabel            matlab.ui.control.Label
        DelayTimeKnob                 matlab.ui.control.Knob
        DecayTimeKnobLabel            matlab.ui.control.Label
        DecayTimeKnob                 matlab.ui.control.Knob
        DelayLabel                    matlab.ui.control.Label
        KarplusStrongLabel            matlab.ui.control.Label
        KeyboardLabel                 matlab.ui.control.Label
        PresetsButtonGroup            matlab.ui.container.ButtonGroup
        GuitarButton                  matlab.ui.control.ToggleButton
        SynthButton                   matlab.ui.control.ToggleButton
        EchoButton                    matlab.ui.control.ToggleButton
        MetallicButton                matlab.ui.control.ToggleButton
    end


    properties (Access = private)
        %Initial variables
        fs = 44100; % Sample Rate
        T = 1/44100; % Sampling interval
        outLength = 3; % Length of output in seconds
        
        % Karplus Strong variables
        octave = 110; % Initial Octave - A2
    end

    methods (Access = private)
    
        function karplus(app, f0)
            L = floor(app.fs/f0);   % String Length in samples
            N = app.outLength*44100; % Number of samples in output
    
            % Delay line 
            delayLine = excitationSignal(app, L, f0);
            
            % Plot time domain response of excitation signal
            subplot(3, 1, 1);
            plot(delayLine);
            title('Excitation Signal');
            xlabel('Time (samples)');
            ylabel('Amplitude');
            
            % Initialize output
            out = zeros(1,N);
            
            % Karplus Strong Loop
            out = loop(delayLine, N, app.FeedbackGainSlider.Value, app.LoopLowpassFilterCutoffFrequencySlider.Value, app.fs);
            
            % Delay Effect - Comb Filter
            Tc = app.DelayTimeKnob.Value; % Delay in seconds
            M = Tc/app.T; % Number of sampling intervals
            RT60 = app.DecayTimeKnob.Value; % Time required for echoes to decay by 60dB
            g = 0.001^(Tc/RT60); % Adder
           
            b = [1 zeros(1, round(M))]; % Filter Numerator
            a = [1 zeros(1, round(M)-1) -g]; % Filter Denominator
            
            if app.DelayTimeKnob.Value>0 % Exception for when delay time is set to 0, as this causes no output
                out = filter(b, a, out); % Apply filter
            end
            
            % Play Sound
            soundsc(out, app.fs);
            
            % Plot time domain response of output
            subplot(3, 1, 2);
            plot(out);
            title('Output');
            xlabel('Time (samples)');
            ylabel('Amplitude');
    
            fftSize = 8192;
            f = (0:fftSize-1)*(app.fs/fftSize);
            
            % Plot frequency domain response of output
            subplot(3, 1, 3);
            spectrogram(out,1024, 256, 1024, app.fs, 'yaxis');
            
            % Save in a wav file
            audiowrite('Output.wav', out, app.fs);
        end
        
        function output = excitationSignal(app, L, f0)
            t = 0:L; % Create vector of values for string
            
            % If statement for button group
            if app.WhiteNoiseButton.Value == true
                output = (rand(1, L) - 0.5); % Create white noise
            elseif app.ChirpButton.Value == true
                output = chirp(t/(app.fs/f0),0,L/(app.fs/f0),app.EndfrequencyHzEditField.Value) / 2; % Create chirp which goes from 0Hz to what is defined by the user in the edit field
            elseif app.SineButton.Value == true
                output = sin(2*pi*(t/(app.fs/f0))) / 2; % Create one period of a sine wave
            elseif app.TriangleButton.Value == true
                output = sawtooth(2*pi*(t/(app.fs/f0))) / 2; % Create one period of a triangle wave
            elseif app.SquareButton.Value == true
                output = square(2*pi*(t/(app.fs/f0))) / 2; % Create one period of a square wave
            end
                    
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            subplot(3, 1, 1);
            subplot(3, 1, 2);
            subplot(3, 1, 3);
            
        end

        % Value changed function: EndfrequencyHzEditField
        function ChirpFreq(app, event)
            value = app.EndfrequencyHzEditField.Value;
        end

        % Value changed function: 
        % LoopLowpassFilterCutoffFrequencySlider
        function CutoffFreqChanged(app, event)
            value = app.LoopLowpassFilterCutoffFrequencySlider.Value;
        end

        % Value changed function: DecayTimeKnob
        function DecayTimeChanged(app, event)
            value = app.DecayTimeKnob.Value;
        end

        % Value changed function: DelayTimeKnob
        function DelayTimeChanged(app, event)
            value = app.DelayTimeKnob.Value;
        end

        % Value changed function: FeedbackGainSlider
        function FeedbackGainChange(app, event)
            value = app.FeedbackGainSlider.Value;
        end

        % Button pushed function: OctaveDownButton
        function OctaveDown(app, event)
            app.octave = app.octave / 2;
        end

        % Button pushed function: OctaveUpButton
        function OctaveUp(app, event)
            app.octave = app.octave * 2;
        end

        % Callback function
        function feedbackGainValChange(app, event)
            changingValue = event.Value;
            app.gain = changingValue;
        end

        % Button pushed function: AButton
        function note_A(app, event)
            f0 = app.octave; % Fundamental Frequency of string in Hz.
            karplus(app, f0);
        end

        % Button pushed function: AButton_2
        function note_A_sharp(app, event)
            f0 = app.octave * 2.^(1/12);
            karplus(app, f0);
        end

        % Button pushed function: BButton
        function note_B(app, event)
            f0 = app.octave * 2.^(2/12);
            karplus(app, f0);
        end

        % Button pushed function: CButton
        function note_C(app, event)
            f0 = app.octave / 2.^(9/12); 
            karplus(app, f0);
         
        end

        % Button pushed function: CButton_3
        function note_C_2(app, event)
            f0 = app.octave * 2.^(3/12);
            karplus(app, f0);
        end

        % Button pushed function: CButton_2
        function note_C_sharp(app, event)
            f0 = app.octave / 2.^(8/12);
            karplus(app, f0);
        end

        % Button pushed function: CButton_4
        function note_C_sharp_2(app, event)
            f0 = app.octave * 2.^(4/12);
            karplus(app, f0);
        end

        % Button pushed function: DButton
        function note_D(app, event)
            f0 = app.octave / 2.^(7/12);
            karplus(app, f0);
        end

        % Button pushed function: DButton_3
        function note_D_2(app, event)
            f0 = app.octave * 2.^(5/12);
            karplus(app, f0);
        end

        % Button pushed function: DButton_4
        function note_D_2_sharp(app, event)
            f0 = app.octave * 2.^(6/12);
            karplus(app, f0);
        end

        % Button pushed function: DButton_2
        function note_D_sharp(app, event)
            f0 = app.octave / 2.^(6/12);
            karplus(app, f0);
        end

        % Button pushed function: EButton
        function note_E(app, event)
            f0 = app.octave / 2.^(5/12);
            karplus(app, f0);
        end

        % Button pushed function: EButton_2
        function note_E_2(app, event)
            f0 = app.octave * 2.^(7/12);
            karplus(app, f0);
        end

        % Button pushed function: FButton
        function note_F(app, event)
            f0 = app.octave / 2.^(4/12);
            karplus(app, f0);
        end

        % Button pushed function: FButton_2
        function note_F_sharp(app, event)
            f0 = app.octave / 2.^(3/12);
            karplus(app, f0);
        end

        % Button pushed function: GButton
        function note_G(app, event)
            f0 = app.octave / 2.^(2/12);
            karplus(app, f0);
        end

        % Button pushed function: GButton_2
        function note_G_sharp(app, event)
            f0 = app.octave / 2.^(1/12);
            karplus(app, f0);
        end

        % Selection changed function: PresetsButtonGroup
        function PresetChanged(app, event)
            selectedButton = app.PresetsButtonGroup.SelectedObject;
            if app.GuitarButton.Value == true
                app.WhiteNoiseButton.Value = true;
                app.DelayTimeKnob.Value = 0;
                app.DecayTimeKnob.Value = 0;
                app.LoopLowpassFilterCutoffFrequencySlider.Value = 4000;
                app.FeedbackGainSlider.Value = 0.998;
            elseif app.SynthButton.Value == true
                app.SquareButton.Value = true;
                app.DelayTimeKnob.Value = 0;
                app.DecayTimeKnob.Value = 0;
                app.LoopLowpassFilterCutoffFrequencySlider.Value = 15000;
                app.FeedbackGainSlider.Value = 0.97;
            elseif app.EchoButton.Value == true
                app.ChirpButton.Value = true;
                app.DelayTimeKnob.Value = 0.1;
                app.DecayTimeKnob.Value = 5;
                app.LoopLowpassFilterCutoffFrequencySlider.Value = 7000;
                app.FeedbackGainSlider.Value = 0.975;
                app.EndfrequencyHzEditField.Value = 30;
            elseif app.MetallicButton.Value == true
                app.TriangleButton.Value = true;
                app.DelayTimeKnob.Value = 0.02;
                app.DecayTimeKnob.Value = 2;
                app.LoopLowpassFilterCutoffFrequencySlider.Value = 5000;
                app.FeedbackGainSlider.Value = 0.995;
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @note_C, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @note_D, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @note_E, true);
            app.EButton.Text = 'E';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @note_F, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @note_G, true);
            app.GButton.Text = 'G';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @note_A, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @note_B, true);
            app.BButton.Text = 'B';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_C_sharp, true);
            app.CButton_2.Text = 'C#';
            app.DButton_2 = uibutton(app.UIFigure, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_D_sharp, true);
            app.DButton_2.Text = 'D#';
            app.FButton_2 = uibutton(app.UIFigure, 'push');
            app.FButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_F_sharp, true);
            app.FButton_2.Text = 'F#';
            app.GButton_2 = uibutton(app.UIFigure, 'push');
            app.GButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_G_sharp, true);
            app.GButton_2.Text = 'G#';
            app.AButton_2 = uibutton(app.UIFigure, 'push');
            app.AButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_A_sharp, true);
            app.AButton_2.Text = 'A#';
            app.CButton_3 = uibutton(app.UIFigure, 'push');
            app.CButton_3.ButtonPushedFcn = createCallbackFcn(app, @note_C_2, true);
            app.CButton_3.Text = 'C';
            app.DButton_3 = uibutton(app.UIFigure, 'push');
            app.DButton_3.ButtonPushedFcn = createCallbackFcn(app, @note_D_2, true);
            app.DButton_3.Text = 'D';
            app.EButton_2 = uibutton(app.UIFigure, 'push');
            app.EButton_2.ButtonPushedFcn = createCallbackFcn(app, @note_E_2, true);
            app.EButton_2.Text = 'E';
            app.CButton_4 = uibutton(app.UIFigure, 'push');
            app.CButton_4.ButtonPushedFcn = createCallbackFcn(app, @note_C_sharp_2, true);
            app.CButton_4.Text = 'C#';
            app.DButton_4 = uibutton(app.UIFigure, 'push');
            app.DButton_4.ButtonPushedFcn = createCallbackFcn(app, @note_D_2_sharp, true);
            app.DButton_4.Text = 'D#';
            app.OctaveDownButton = uibutton(app.UIFigure, 'push');
            app.OctaveDownButton.ButtonPushedFcn = createCallbackFcn(app, @OctaveDown, true);
            app.OctaveDownButton.Text = 'Octave Down';
            app.OctaveUpButton = uibutton(app.UIFigure, 'push');
            app.OctaveUpButton.ButtonPushedFcn = createCallbackFcn(app, @OctaveUp, true);
            app.OctaveUpButton.Text = 'Octave Up';
            app.FeedbackGainSliderLabel = uilabel(app.UIFigure);
            app.FeedbackGainSliderLabel.Text = 'Feedback Gain';
            app.FeedbackGainSlider = uislider(app.UIFigure);
            app.FeedbackGainSlider.Limits = [0.97 0.999];
            app.FeedbackGainSlider.MajorTickLabels = {'0.97', '', '', '', '', '', '', '', '', '0.999'};
            app.FeedbackGainSlider.ValueChangedFcn = createCallbackFcn(app, @FeedbackGainChange, true);
            app.FeedbackGainSlider.Value = 0.998;
            app.LoopLowpassFilterCutoffFrequencySliderLabel = uilabel(app.UIFigure);
            app.LoopLowpassFilterCutoffFrequencySliderLabel.Text = {'Loop LowpassFilter '; 'Cutoff Frequency'};
            app.LoopLowpassFilterCutoffFrequencySlider = uislider(app.UIFigure);
            app.LoopLowpassFilterCutoffFrequencySlider.Limits = [0 20000];
            app.LoopLowpassFilterCutoffFrequencySlider.MajorTicks = [0 5000 10000 15000 20000];
            app.LoopLowpassFilterCutoffFrequencySlider.MajorTickLabels = {'0Hz', '5000', '1e+04', '1.5e+04', '20kHz'};
            app.LoopLowpassFilterCutoffFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqChanged, true);
            app.LoopLowpassFilterCutoffFrequencySlider.Value = 4000;
            app.ExcitationSignalButtonGroup = uibuttongroup(app.UIFigure);
            app.ExcitationSignalButtonGroup.Title = 'Excitation Signal';
            app.WhiteNoiseButton = uitogglebutton(app.ExcitationSignalButtonGroup);
            app.WhiteNoiseButton.Text = 'White Noise';
            app.WhiteNoiseButton.Value = true;
            app.ChirpButton = uitogglebutton(app.ExcitationSignalButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.SineButton = uitogglebutton(app.ExcitationSignalButtonGroup);
            app.SineButton.Text = 'Sine ';
            app.TriangleButton = uitogglebutton(app.ExcitationSignalButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.SquareButton = uitogglebutton(app.ExcitationSignalButtonGroup);
            app.SquareButton.Text = 'Square';
            app.EndfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.EndfrequencyHzEditFieldLabel.Text = 'End frequency (Hz)';
            app.EndfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @ChirpFreq, true);
            app.EndfrequencyHzEditField.Limits = [0 20000];
            app.EndfrequencyHzEditField.ValueDisplayFormat = '%11.5g';
            app.EndfrequencyHzEditField.Value = 20;
            app.DelayTimeKnobLabel = uilabel(app.UIFigure);
            app.DelayTimeKnobLabel.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.UIFigure, 'continuous');
            app.DelayTimeKnob.Limits = [0 0.2];
            app.DelayTimeKnob.ValueChangedFcn = createCallbackFcn(app, @DelayTimeChanged, true);
            app.DecayTimeKnobLabel = uilabel(app.UIFigure);
            app.DecayTimeKnobLabel.Text = 'Decay Time';
            app.DecayTimeKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayTimeKnob.Limits = [0 5];
            app.DecayTimeKnob.ValueChangedFcn = createCallbackFcn(app, @DecayTimeChanged, true);
            app.DelayLabel = uilabel(app.UIFigure);
            app.DelayLabel.Text = 'Delay';
            app.KarplusStrongLabel = uilabel(app.UIFigure);
            app.KarplusStrongLabel.Text = 'Karplus Strong';
            app.KeyboardLabel = uilabel(app.UIFigure);
            app.KeyboardLabel.Text = 'Keyboard';
            app.PresetsButtonGroup = uibuttongroup(app.UIFigure);
            app.PresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PresetChanged, true);
            app.PresetsButtonGroup.Title = 'Presets';
            app.GuitarButton = uitogglebutton(app.PresetsButtonGroup);
            app.GuitarButton.Text = 'Guitar';
            app.GuitarButton.Value = true;
            app.SynthButton = uitogglebutton(app.PresetsButtonGroup);
            app.SynthButton.Text = 'Synth';
            app.EchoButton = uitogglebutton(app.PresetsButtonGroup);
            app.EchoButton.Text = 'Echo';
            app.MetallicButton = uitogglebutton(app.PresetsButtonGroup);
            app.MetallicButton.Text = 'Metallic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KarplusStrongApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end